classdef two_tank_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoubleTankProcess               matlab.ui.Figure
        SystemSettingPanel              matlab.ui.container.Panel
        StartStopSwitch                 matlab.ui.control.Switch
        SimRealSwitch                   matlab.ui.control.Switch
        TapSwitch                       matlab.ui.control.Switch
        ControllerSwitch                matlab.ui.control.Switch
        PlotSettingPanel                matlab.ui.container.Panel
        AxesResetButton                 matlab.ui.control.Button
        TspanEditFieldLabel             matlab.ui.control.Label
        TspanEditField                  matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        DataAquisitionPanel             matlab.ui.container.Panel
        DataAquisition                  matlab.ui.control.UIAxes
        UppertankLabel                  matlab.ui.control.Label
        tank1Gauge                      matlab.ui.control.LinearGauge
        LowertankLabel                  matlab.ui.control.Label
        tank2Gauge                      matlab.ui.control.LinearGauge
        tank1Overflow                   matlab.ui.control.Lamp
        tank2Overflow                   matlab.ui.control.Lamp
        SignalsPanel                    matlab.ui.container.Panel
        ReferenceCheckBox               matlab.ui.control.CheckBox
        tank1CheckBox                   matlab.ui.control.CheckBox
        tank2CheckBox                   matlab.ui.control.CheckBox
        ControlSignalCheckBox           matlab.ui.control.CheckBox
        PredictionCheckBox              matlab.ui.control.CheckBox
        ManualPIDMPC                    matlab.ui.container.TabGroup
        ManualTab                       matlab.ui.container.Tab
        InputSlider                     matlab.ui.control.Slider
        InputEditField_2Label           matlab.ui.control.Label
        InputEditField                  matlab.ui.control.NumericEditField
        SimulationspeedupDropDownLabel  matlab.ui.control.Label
        ManualSimulationspeedupDropDown  matlab.ui.control.DropDown
        PIDTab                          matlab.ui.container.Tab
        RefSlider                       matlab.ui.control.Slider
        ReferenceLabel_2                matlab.ui.control.Label
        RefEditField                    matlab.ui.control.NumericEditField
        KLabel_2                        matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        TILabel_2                       matlab.ui.control.Label
        TiEditField                     matlab.ui.control.NumericEditField
        TDLabel_2                       matlab.ui.control.Label
        TdEditField                     matlab.ui.control.NumericEditField
        TankSwitch                      matlab.ui.control.Switch
        SimulationspeedupDropDownLabel_2  matlab.ui.control.Label
        PIDSimulationspeedupDropDown    matlab.ui.control.DropDown
        LQRTab_2                        matlab.ui.container.Tab
        LQRPenaltyPanel                 matlab.ui.container.Panel
        InputpenaltyEditFieldLabel_2    matlab.ui.control.Label
        LQRInputpenaltyEditField        matlab.ui.control.NumericEditField
        LowertankpenaltyLabel_2         matlab.ui.control.Label
        LQRTank2penaltyEditField        matlab.ui.control.NumericEditField
        UppertankpenaltyLabel_2         matlab.ui.control.Label
        LQRTank1penaltyEditField        matlab.ui.control.NumericEditField
        ReferenceLabel_3                matlab.ui.control.Label
        LQRRefEditField                 matlab.ui.control.NumericEditField
        DisturbancecompensationPanel_2  matlab.ui.container.Panel
        LQRDisturbancecompensationSwitch  matlab.ui.control.Switch
        ReferencechoicePanelLQR         matlab.ui.container.Panel
        ReferencechoiceSwitchLQR        matlab.ui.control.Switch
        SimulationspeedupDropDownLabel_3  matlab.ui.control.Label
        LQRSimulationspeedupDropDown    matlab.ui.control.DropDown
        MPCTab                          matlab.ui.container.Tab
        PenaltyPanel                    matlab.ui.container.Panel
        InputpenaltyEditFieldLabel      matlab.ui.control.Label
        MPCInputpenaltyEditField        matlab.ui.control.NumericEditField
        LowertankpenaltyLabel           matlab.ui.control.Label
        MPCTank2penaltyEditField        matlab.ui.control.NumericEditField
        UppertankpenaltyLabel           matlab.ui.control.Label
        MPCTank1penaltyEditField        matlab.ui.control.NumericEditField
        ReferenceLabel_4                matlab.ui.control.Label
        MPCRefEditField                 matlab.ui.control.NumericEditField
        PredictionhorizonPanel          matlab.ui.container.Panel
        ClosedloopEditFieldLabel        matlab.ui.control.Label
        CloseloopEditField              matlab.ui.control.NumericEditField
        OpenloopEditFieldLabel          matlab.ui.control.Label
        OpenloopEditField               matlab.ui.control.NumericEditField
        MPCchoicePanel                  matlab.ui.container.Panel
        CloseOpenMPCSwitch              matlab.ui.control.Switch
        ReferencechoicePanel            matlab.ui.container.Panel
        ReferencechoiceSwitch           matlab.ui.control.Switch
        RicattiterminalpenaltyPanel     matlab.ui.container.Panel
        RiccatiterminalpenaltySwitch    matlab.ui.control.Switch
        DisturbancecompensationPanel    matlab.ui.container.Panel
        DisturbancecompensationSwitch   matlab.ui.control.Switch
        ReferencepreviewPanel           matlab.ui.container.Panel
        ReferencepreviewSwitch          matlab.ui.control.Switch
        ConstraintsPanel                matlab.ui.container.Panel
        InputconstraintCheckBox         matlab.ui.control.CheckBox
        InputmaxLabel                   matlab.ui.control.Label
        MPCInputConstraintMax           matlab.ui.control.NumericEditField
        InputminLabel                   matlab.ui.control.Label
        MPCInputConstraintMin           matlab.ui.control.NumericEditField
        StateconstraintCheckBox         matlab.ui.control.CheckBox
        TankmaxLabel                    matlab.ui.control.Label
        MPCTankConstraintMax            matlab.ui.control.NumericEditField
        TankminLabel                    matlab.ui.control.Label
        MPCTankConstraintMin            matlab.ui.control.NumericEditField
        ModelparametersButton           matlab.ui.control.Button
        ModelsaveButton                 matlab.ui.control.Button
        CapturePanel                    matlab.ui.container.Panel
        CaptureButton                   matlab.ui.control.Button
        sEditFieldLabel                 matlab.ui.control.Label
        SecondsToCaptureEditField       matlab.ui.control.NumericEditField
        SavetoButton                    matlab.ui.control.Button
        matEditFieldLabel               matlab.ui.control.Label
        SavetoFileEditField             matlab.ui.control.EditField
    end


    properties (Access = public)
        % --- COMPONENTS: main --- %
        HW
        SW
        system
        PID
        Manual
        MPC_CloseLoop
        MPC_OpenLoop
        LQR
        controller
        
        % --- MODEL CONFIGURE --- %
        MPCModelConfigure
        
        % --- COMPONENTS: timer --- %
        Timer
        AppIsRunning
        
        % --- COMPONENTS: plotting --- %
        figureOption
        ref_plot       % handle: reference data
        tank1_plot     % handle: tank1 plot
        tank2_plot     % handle: tank2 plot
        controlSignal_plot
        pre_tank1_plot
        pre_tank2_plot
        pre_ref_plot
        pre_input_plot
    end   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('tankClass')
            addpath('mpcModelConfigure')
            processFcn.ProcessCheck(app);
            processFcn.ProcessPrepare(app);
        end

        % Button pushed function: AxesResetButton
        function AxesResetButtonPushed(app, event)
            if app.AppIsRunning
                stop(app.Timer)
                plotFcn.AxisReset(app)
                start(app.Timer)
            else
                plotFcn.AxisReset(app)
            end
        end

        % Callback function
        function CaptureButtonPushed(app, event)
            plotFcn.PlotCapture(app)
        end

        % Value changed function: CloseOpenMPCSwitch
        function CloseOpenMPCSwitchValueChanged(app, event)
            modeFcn.CloseOpenMPC_switch(app,event);
        end

        % Value changed function: CloseloopEditField
        function CloseloopEditFieldValueChanged(app, event)
            app.MPC_CloseLoop.N=event.Value;
            plotFcn.PlotMPCPre(app);
        end

        % Callback function
        function ConSinRefSwitchValueChanged(app, event)
            switch event.Value
                case 'Constant reference'
                    app.MPC_CloseLoop.ref_flag=false;
                case 'Sinusoid reference'
                    app.MPC_CloseLoop.ref_flag=true;
            end
            
        end

        % Value changed function: ControlSignalCheckBox
        function ControlSignalCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.controlSignal_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
                set(app.pre_input_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
            end
        end

        % Value changed function: ControllerSwitch
        function ControllerSwitchValueChanged(app, event)
            modeFcn.ControllerApply(app,event.Value);
        end

        % Value changed function: DisturbancecompensationSwitch
        function DisturbancecompensationSwitchValueChanged(app, event)
            switch event.Value
                case 'On'
                    app.MPC_CloseLoop.disturbance_flag=true;
                    app.MPC_OpenLoop.disturbance_flag=true;
                case 'Off'
                    app.MPC_CloseLoop.disturbance_flag=false;
                    app.MPC_OpenLoop.disturbance_flag=false;
            end
        end

        % Close request function: DoubleTankProcess
        function DoubleTankProcessCloseRequest(app, event)
            % --- MODEL CONFIGURE WINDOW CLOSE --- %
            if ~isempty(app.MPCModelConfigure) && isvalid(app.MPCModelConfigure.UIfigure_h.figure1)
                delete(app.MPCModelConfigure.UIfigure_h.figure1)
            end
            % --- PROCESS STOP AND WINDOW CLOSE --- %
            processFcn.ProcessStop(app);
            processFcn.ProcessDelete(app);
            delete(app);
        end

        % Value changed function: InputEditField, InputSlider
        function InputValueChanged(app, event)
            app.InputEditField.Value = event.Value;
            app.InputSlider.Value = event.Value;
            app.Manual.controlSignal_before=event.Value;
        end

        % Value changed function: InputconstraintCheckBox
        function InputconstraintCheckBoxValueChanged(app, event)
            if event.Value
                app.MPC_CloseLoop.input_constraint=true;
                app.MPC_OpenLoop.input_constraint=true;
                app.MPCInputConstraintMin.Enable='On';
                app.MPCInputConstraintMax.Enable='On';
            else
                app.MPC_CloseLoop.input_constraint=false;
                app.MPC_OpenLoop.input_constraint=false;
                app.MPCInputConstraintMin.Enable='Off';
                app.MPCInputConstraintMax.Enable='Off';
            end
        end

        % Callback function
        function IresetButtonPushed(app, event)
            app.PID.I_state = 0;
        end

        % Value changed function: KEditField
        function KValueChanged(app, event)
            app.PID.K = event.Value;
        end

        % Value changed function: LQRDisturbancecompensationSwitch
        function LQRDisturbancecompensationSwitchValueChanged(app, event)
            switch event.Value
                case 'On'
                    app.LQR.disturbance_flag=true;
                case 'Off'
                    app.LQR.disturbance_flag=false;
            end
        end

        % Value changed function: LQRInputpenaltyEditField
        function LQRInputpenaltyEditFieldValueChanged(app, event)
            inputPenalty=event.Value*(0.25)^2;
            app.LQR.R(1,1)=inputPenalty;
            app.LQR.LQR_Prepare();
        end

        % Value changed function: LQRRefEditField
        function LQRRefEditFieldValueChanged(app, event)
            app.LQR.reference=event.Value;
        end

        % Callback function
        function LQRReferencechoiceSwitchValueChanged(app, event)
            switch event.Value
                case 'Sinusoid'
                    app.LQR.ref_flag=true;
                case 'Constant'
                    app.LQR.ref_flag=false;
            end
        end

        % Value changed function: LQRTank1penaltyEditField
        function LQRTank1penaltyEditFieldValueChanged(app, event)
            statePenalty=event.Value*(3)^2;
            app.LQR.Q(1,1)=statePenalty;
            app.LQR.LQR_Prepare();
        end

        % Value changed function: LQRTank2penaltyEditField
        function LQRTank2penaltyEditFieldValueChanged(app, event)
            statePenalty=event.Value*(3)^2;
            app.LQR.Q(2,2)=statePenalty;
            app.LQR.LQR_Prepare();
        end

        % Value changed function: MPCInputConstraintMax
        function MPCInputConstraintMaxValueChanged(app, event)
            app.MPC_CloseLoop.input_constraint_max=event.Value;
            app.MPC_OpenLoop.input_constraint_max=event.Value;
        end

        % Value changed function: MPCInputConstraintMin
        function MPCInputConstraintMinValueChanged(app, event)
            app.MPC_CloseLoop.input_constaint_min=event.Value;
            app.MPC_OpenLoop.input_constaint_min=event.Value;
        end

        % Value changed function: MPCInputpenaltyEditField
        function MPCInputpenaltyEditFieldValueChanged(app, event)
            inputPenalty=event.Value*(0.25)^2;
            app.MPC_CloseLoop.R=inputPenalty;
            app.MPC_OpenLoop.R=inputPenalty;
            % --- UPDATE THE TERMINAL RICATTI PENALTY --- %
            doubletank_d = app.MPC_CloseLoop.pre_model; % the same model is used for CloseLoop and OpenLoop
            [Close_P,~,~] = dare(doubletank_d.A,doubletank_d.B,app.MPC_CloseLoop.Q,app.MPC_CloseLoop.R);
            [Open_P,~,~] = dare(doubletank_d.A,doubletank_d.B,app.MPC_OpenLoop.Q,app.MPC_OpenLoop.R);
            app.MPC_CloseLoop.ricatti_Qf = Close_P;
            app.MPC_OpenLoop.ricatti_Qf = Open_P;
        end

        % Value changed function: MPCRefEditField
        function MPCRefEditFieldValueChanged(app, event)
            % --- STATE REFERENCE SETTING --- %
            app.MPC_CloseLoop.reference=event.Value;
            app.MPC_OpenLoop.reference=event.Value;
        end

        % Value changed function: MPCTank1penaltyEditField
        function MPCTank1penaltyEditFieldValueChanged(app, event)
            tank1Penalty=event.Value*(3)^2;
            app.MPC_CloseLoop.Q(1,1)=tank1Penalty;
            app.MPC_OpenLoop.Q(1,1)=tank1Penalty;
            % --- UPDATE THE TERMINAL RICATTI PENALTY --- %
            doubletank_d = app.MPC_CloseLoop.pre_model; % the same model is used for CloseLoop and OpenLoop
            [Close_P,~,~] = dare(doubletank_d.A,doubletank_d.B,app.MPC_CloseLoop.Q,app.MPC_CloseLoop.R);
            [Open_P,~,~] = dare(doubletank_d.A,doubletank_d.B,app.MPC_OpenLoop.Q,app.MPC_OpenLoop.R);
            app.MPC_CloseLoop.ricatti_Qf = Close_P;
            app.MPC_OpenLoop.ricatti_Qf = Open_P;
        end

        % Value changed function: MPCTank2penaltyEditField
        function MPCTank2penaltyEditFieldValueChanged(app, event)
            tank2Penalty=event.Value*(3)^2;
            app.MPC_CloseLoop.Q(2,2)=tank2Penalty;
            app.MPC_OpenLoop.Q(2,2)=tank2Penalty;
            % --- UPDATE THE TERMINAL RICATTI PENALTY --- %
            doubletank_d = app.MPC_CloseLoop.pre_model; % the same model is used for CloseLoop and OpenLoop
            [Close_P,~,~] = dare(doubletank_d.A,doubletank_d.B,app.MPC_CloseLoop.Q,app.MPC_CloseLoop.R);
            [Open_P,~,~] = dare(doubletank_d.A,doubletank_d.B,app.MPC_OpenLoop.Q,app.MPC_OpenLoop.R);
            app.MPC_CloseLoop.ricatti_Qf = Close_P;
            app.MPC_OpenLoop.ricatti_Qf = Open_P;
        end

        % Value changed function: MPCTankConstraintMax
        function MPCTankConstraintMaxValueChanged(app, event)
            app.MPC_CloseLoop.state_constraint_max=event.Value;
            app.MPC_OpenLoop.state_constraint_max=event.Value;
        end

        % Value changed function: MPCTankConstraintMin
        function MPCTankConstraintMinValueChanged(app, event)
            app.MPC_CloseLoop.state_constaint_min=event.Value;
            app.MPC_OpenLoop.state_constaint_min=event.Value;
        end

        % Selection change function: ManualPIDMPC
        function ManualPIDMPCSwitchValueChanged(app, event)
            modeFcn.ControllerSwitch(app,event.NewValue.Title)
        end

        % Button pushed function: ModelparametersButton
        function ModelparametersButtonPushed(app, event)
            if isempty(app.MPCModelConfigure) || isempty(app.MPCModelConfigure.u0)
                app.MPCModelConfigure = ModelConfigureClass();
            elseif ~isvalid(app.MPCModelConfigure.UIfigure_h.figure1)
                app.MPCModelConfigure.ModelReopen();
            end
        end

        % Button pushed function: ModelsaveButton
        function ModelsaveButtonPushed(app, event)
            app.MPCModelConfigure.ModelSave(app);
        end

        % Value changed function: OpenloopEditField
        function OpenloopEditFieldValueChanged(app, event)
            app.MPC_OpenLoop.N=event.Value;
        end

        % Value changed function: PredictionCheckBox
        function PredictionCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.pre_input_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
                set(app.pre_tank1_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
                set(app.pre_tank2_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
                set(app.pre_ref_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
            end
        end

        % Value changed function: RefSlider
        function RefSliderValueChanged(app, event)
            app.system.ref = event.Value;
            app.RefEditField.Value = event.Value;
        end

        % Value changed function: RefEditField
        function RefValueChanged(app, event)
            app.system.ref = event.Value;
            app.RefSlider.Value = event.Value;
        end

        % Value changed function: ReferenceCheckBox
        function ReferenceCheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.ref_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
                set(app.pre_ref_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
            end
        end

        % Value changed function: ReferencechoiceSwitchLQR
        function ReferencechoiceSwitchLQRValueChanged(app, event)
            switch event.Value
                case 'Sinusoid'
                    app.LQR.ref_flag=true;
                case 'Constant'
                    app.LQR.ref_flag=false;
            end
        end

        % Value changed function: ReferencechoiceSwitch
        function ReferencechoiceSwitchValueChanged(app, event)
            switch event.Value
                case 'Sinusoid'
                    app.MPC_CloseLoop.ref_flag=true;
                    app.MPC_OpenLoop.ref_flag=true;
                case 'Constant'
                    app.MPC_CloseLoop.ref_flag=false;
                    app.MPC_OpenLoop.ref_flag=false;
            end
        end

        % Value changed function: ReferencepreviewSwitch
        function ReferencepreviewValueChanged(app, event)
            switch event.Value
                case 'On'
                    app.MPC_CloseLoop.preview = true;
                    app.MPC_OpenLoop.preview = true;
                case 'Off'
                    app.MPC_CloseLoop.preview = false;
                    app.MPC_OpenLoop.preview = false;
            end
        end

        % Value changed function: RiccatiterminalpenaltySwitch
        function RiccatiterminalpenaltyValueChanged(app, event)
            switch event.Value
                case 'On'
                    app.MPC_CloseLoop.Qf = app.MPC_CloseLoop.ricatti_Qf;
                    app.MPC_OpenLoop.Qf = app.MPC_OpenLoop.ricatti_Qf;
                case 'Off'
                    app.MPC_CloseLoop.Qf = app.MPC_CloseLoop.Q;
                    app.MPC_OpenLoop.Qf = app.MPC_OpenLoop.Q;
            end
        end

        % Button pushed function: SavetoButton
        function SavetoButtonPushed(app, event)
            num2save=20*app.SecondsToCaptureEditField.Value;
            upper_tank = app.system.History(end-num2save:end,1);
            lower_tank = app.system.History(end-num2save:end,2);
            input = app.system.History(end-num2save:end,3);
            reference = app.system.History(end-num2save:end,4);
            time = linspace((app.system.counter-num2save)/20,app.system.counter/20,num2save+1)';
            save([app.SavetoFileEditField.Value,'.mat'],'upper_tank','lower_tank','input','reference','time');
        end

        % Value changed function: SecondsToCaptureEditField
        function SecondsToCaptureEditFieldValueChanged(app, event)
            if app.AppIsRunning
                stop(app.Timer)
                plotFcn.TspanReset(app,event)
                start(app.Timer)
            else
                plotFcn.TspanReset(app,event)
            end
        end

        % Value changed function: SimRealSwitch
        function SimRealSwitchValueChanged(app, event)
            switch event.Value
                case 'Simulator'
                    modeFcn.SW_mode(app);
                case 'Real time'
                    modeFcn.HW_mode(app);
            end
        end

        % Value changed function: LQRSimulationspeedupDropDown, 
        % ManualSimulationspeedupDropDown, PIDSimulationspeedupDropDown
        function SimulationspeedupDropDownValueChanged(app, event)
            switch event.Value
                case '1x'
                    app.SW.simulation_speed_scale = 5;
                    app.ManualSimulationspeedupDropDown.Value='1x';
                    app.PIDSimulationspeedupDropDown.Value='1x';
                    app.LQRSimulationspeedupDropDown.Value='1x';
                case '2x'
                    app.SW.simulation_speed_scale = 4;
                    app.ManualSimulationspeedupDropDown.Value='2x';
                    app.PIDSimulationspeedupDropDown.Value='2x';
                    app.LQRSimulationspeedupDropDown.Value='2x';
                case '3x'
                    app.SW.simulation_speed_scale = 3;
                    app.ManualSimulationspeedupDropDown.Value='3x';
                    app.PIDSimulationspeedupDropDown.Value='3x';
                    app.LQRSimulationspeedupDropDown.Value='3x';
                case '4x'
                    app.SW.simulation_speed_scale = 2;
                    app.ManualSimulationspeedupDropDown.Value='4x';
                    app.PIDSimulationspeedupDropDown.Value='4x';
                    app.LQRSimulationspeedupDropDown.Value='4x';
                case '5x'
                    app.SW.simulation_speed_scale = 1;
                    app.ManualSimulationspeedupDropDown.Value='5x';
                    app.PIDSimulationspeedupDropDown.Value='5x';
                    app.LQRSimulationspeedupDropDown.Value='5x';
            end
            if app.AppIsRunning
                stop(app.Timer)
                app.Timer.Period = app.system.Ts*(app.SW.simulation_speed_scale/5);
                start(app.Timer)
            else
                app.Timer.Period = app.system.Ts*(app.SW.simulation_speed_scale/5);
            end
        end

        % Value changed function: StartStopSwitch
        function StartStopSwitchCallback(app, event)
            app.StartStopSwitch.Enable = 'Off';
            switch event.Value
                case 'Start'
                    processFcn.ProcessStart(app);
                case 'Stop'
                    processFcn.ProcessStop(app);
                    if isa(app.system,'HW')
                        app.MPC_CloseLoop.observor_flush=true;
                        app.LQR.observor_flush=true;
                    end
            end
            app.StartStopSwitch.Enable = 'On';
        end

        % Value changed function: StateconstraintCheckBox
        function StateconstraintCheckBoxValueChanged(app, event)
            if event.Value
                app.MPC_CloseLoop.state_constraint=true;
                app.MPC_OpenLoop.state_constraint=true;
                app.MPCTankConstraintMin.Enable='On';
                app.MPCTankConstraintMax.Enable='On';
            else
                app.MPC_CloseLoop.state_constraint=false;
                app.MPC_OpenLoop.state_constraint=false;
                app.MPCTankConstraintMin.Enable='Off';
                app.MPCTankConstraintMax.Enable='Off';
            end
        end

        % Value changed function: TankSwitch
        function TankSwitchValueChanged(app, event)
            switch event.Value
                case 'Upper tank'
                   app.HW.tankChoice=1;
                   app.SW.tankChoice=1;
                case 'Lower tank'
                   app.HW.tankChoice=2;
                   app.SW.tankChoice=2;
            end
        end

        % Value changed function: TapSwitch
        function TapSwitchValueChanged(app, event)
            switch event.Value
                case 'Tap close'
                    app.SW.tap_flag=false;
                case 'Tap open'
                    app.SW.tap_flag=true;
            end
        end

        % Value changed function: TdEditField
        function TdValueChanged(app, event)
            app.PID.Td = event.Value;
        end

        % Value changed function: TiEditField
        function TiValueChanged(app, event)
            % --- TI UPDATE --- %
            if event.Value>99999
                app.TiEditField.Value = Inf;
                app.PID.Ti = Inf;
                app.PID.Ie=0;
            else
                app.PID.Ie = app.PID.Ie*(event.Value/app.PID.Ti);
                app.PID.Ti = event.Value;
            end
        end

        % Value changed function: TspanEditField
        function TspanEditFieldValueChanged(app, event)
            if app.AppIsRunning
                stop(app.Timer)
                plotFcn.TspanReset(app,event)
                start(app.Timer)
            else
                plotFcn.TspanReset(app,event)
            end
            app.SecondsToCaptureEditField.Value=event.Value;
        end

        % Value changed function: YmaxEditField
        function YmaxEditFieldValueChanged(app, event)
            app.figureOption.axis_lim(1,4)=event.Value;
            app.DataAquisition.YLim(2)=event.Value;
        end

        % Value changed function: YminEditField
        function YminEditFieldValueChanged(app, event)
            app.figureOption.axis_lim(1,3)=event.Value;
            app.DataAquisition.YLim(1)=event.Value;
        end

        % Value changed function: tank1CheckBox
        function tank1CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank1_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
                set(app.pre_tank1_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
            end
        end

        % Value changed function: tank2CheckBox
        function tank2CheckBoxValueChanged(app, event)
            if ~event.Value
                set(app.tank2_plot,'YData',NaN(app.figureOption.NumSampleToPlot,1));
                set(app.pre_tank2_plot,'YData',NaN(app.MPC_CloseLoop.N,1));
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoubleTankProcess = uifigure;
            app.DoubleTankProcess.Name = 'Double tank process';
            app.DoubleTankProcess.CloseRequestFcn = createCallbackFcn(app, @DoubleTankProcessCloseRequest, true);
            app.SystemSettingPanel = uipanel(app.DoubleTankProcess);
            app.SystemSettingPanel.Title = 'System settings';
            app.StartStopSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.StartStopSwitch.Items = {'Stop', 'Start'};
            app.StartStopSwitch.ValueChangedFcn = createCallbackFcn(app, @StartStopSwitchCallback, true);
            app.StartStopSwitch.Value = 'Stop';
            app.SimRealSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.SimRealSwitch.Items = {'Simulator', 'Real time'};
            app.SimRealSwitch.ValueChangedFcn = createCallbackFcn(app, @SimRealSwitchValueChanged, true);
            app.SimRealSwitch.Value = 'Simulator';
            app.TapSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.TapSwitch.Items = {'Tap close', 'Tap open'};
            app.TapSwitch.ValueChangedFcn = createCallbackFcn(app, @TapSwitchValueChanged, true);
            app.TapSwitch.Value = 'Tap close';
            app.ControllerSwitch = uiswitch(app.SystemSettingPanel, 'slider');
            app.ControllerSwitch.Items = {'Manual', 'Manual'};
            app.ControllerSwitch.ValueChangedFcn = createCallbackFcn(app, @ControllerSwitchValueChanged, true);
            app.ControllerSwitch.Value = 'Manual';
            app.PlotSettingPanel = uipanel(app.DoubleTankProcess);
            app.PlotSettingPanel.Title = 'Plot settings';
            app.AxesResetButton = uibutton(app.PlotSettingPanel, 'push');
            app.AxesResetButton.ButtonPushedFcn = createCallbackFcn(app, @AxesResetButtonPushed, true);
            app.AxesResetButton.Text = 'Axes reset';
            app.TspanEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.TspanEditFieldLabel.Text = 'T span';
            app.TspanEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.TspanEditField.ValueChangedFcn = createCallbackFcn(app, @TspanEditFieldValueChanged, true);
            app.TspanEditField.Limits = [10 600];
            app.TspanEditField.ValueDisplayFormat = '%11.3g';
            app.TspanEditField.Value = 100;
            app.YminEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.YminEditField.ValueChangedFcn = createCallbackFcn(app, @YminEditFieldValueChanged, true);
            app.YminEditField.Limits = [-50 45];
            app.YminEditField.ValueDisplayFormat = '%11.3g';
            app.YminEditField.Value = -20;
            app.YmaxEditFieldLabel = uilabel(app.PlotSettingPanel);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.PlotSettingPanel, 'numeric');
            app.YmaxEditField.ValueChangedFcn = createCallbackFcn(app, @YmaxEditFieldValueChanged, true);
            app.YmaxEditField.Limits = [50 150];
            app.YmaxEditField.ValueDisplayFormat = '%11.3g';
            app.YmaxEditField.Value = 120;
            app.DataAquisitionPanel = uipanel(app.DoubleTankProcess);
            app.DataAquisition = uiaxes(app.DataAquisitionPanel);
            xlabel(app.DataAquisition, 'Time [s]')
            ylabel(app.DataAquisition, 'Y [%]')
            app.UppertankLabel = uilabel(app.DataAquisitionPanel);
            app.UppertankLabel.Text = 'Upper tank';
            app.tank1Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.LowertankLabel = uilabel(app.DataAquisitionPanel);
            app.LowertankLabel.Text = 'Lower tank';
            app.tank2Gauge = uigauge(app.DataAquisitionPanel, 'linear');
            app.tank1Overflow = uilamp(app.DataAquisitionPanel);
            app.tank2Overflow = uilamp(app.DataAquisitionPanel);
            app.SignalsPanel = uipanel(app.DoubleTankProcess);
            app.SignalsPanel.Title = 'Signals';
            app.ReferenceCheckBox = uicheckbox(app.SignalsPanel);
            app.ReferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReferenceCheckBoxValueChanged, true);
            app.ReferenceCheckBox.Text = 'Reference';
            app.tank1CheckBox = uicheckbox(app.SignalsPanel);
            app.tank1CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank1CheckBoxValueChanged, true);
            app.tank1CheckBox.Text = 'Upper tank';
            app.tank1CheckBox.Value = true;
            app.tank2CheckBox = uicheckbox(app.SignalsPanel);
            app.tank2CheckBox.ValueChangedFcn = createCallbackFcn(app, @tank2CheckBoxValueChanged, true);
            app.tank2CheckBox.Text = 'Lower tank';
            app.tank2CheckBox.Value = true;
            app.ControlSignalCheckBox = uicheckbox(app.SignalsPanel);
            app.ControlSignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlSignalCheckBoxValueChanged, true);
            app.ControlSignalCheckBox.Text = 'Input';
            app.ControlSignalCheckBox.Value = true;
            app.PredictionCheckBox = uicheckbox(app.SignalsPanel);
            app.PredictionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PredictionCheckBoxValueChanged, true);
            app.PredictionCheckBox.Enable = 'off';
            app.PredictionCheckBox.Text = 'Prediction';
            app.ManualPIDMPC = uitabgroup(app.DoubleTankProcess);
            app.ManualPIDMPC.SelectionChangedFcn = createCallbackFcn(app, @ManualPIDMPCSwitchValueChanged, true);
            app.ManualTab = uitab(app.ManualPIDMPC);
            app.ManualTab.Title = 'Manual';
            app.InputSlider = uislider(app.ManualTab);
            app.InputSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.InputSlider.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputSlider.MinorTicks = [];
            app.InputEditField_2Label = uilabel(app.ManualTab);
            app.InputEditField_2Label.Text = 'Input';
            app.InputEditField = uieditfield(app.ManualTab, 'numeric');
            app.InputEditField.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputEditField.Limits = [0 100];
            app.InputEditField.ValueDisplayFormat = '%11.3g';
            app.SimulationspeedupDropDownLabel = uilabel(app.ManualTab);
            app.SimulationspeedupDropDownLabel.Text = 'Simulation speed up';
            app.ManualSimulationspeedupDropDown = uidropdown(app.ManualTab);
            app.ManualSimulationspeedupDropDown.Items = {'1x', '2x', '3x', '4x', '5x'};
            app.ManualSimulationspeedupDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationspeedupDropDownValueChanged, true);
            app.ManualSimulationspeedupDropDown.Value = '1x';
            app.PIDTab = uitab(app.ManualPIDMPC);
            app.PIDTab.Title = 'PID';
            app.RefSlider = uislider(app.PIDTab);
            app.RefSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.RefSlider.ValueChangedFcn = createCallbackFcn(app, @RefSliderValueChanged, true);
            app.RefSlider.MinorTicks = [];
            app.RefSlider.Value = 60;
            app.ReferenceLabel_2 = uilabel(app.PIDTab);
            app.ReferenceLabel_2.Text = 'Reference';
            app.RefEditField = uieditfield(app.PIDTab, 'numeric');
            app.RefEditField.ValueChangedFcn = createCallbackFcn(app, @RefValueChanged, true);
            app.RefEditField.Limits = [0 100];
            app.RefEditField.RoundFractionalValues = 'on';
            app.RefEditField.ValueDisplayFormat = '%11.3g';
            app.RefEditField.Value = 60;
            app.KLabel_2 = uilabel(app.PIDTab);
            app.KLabel_2.Text = 'K';
            app.KEditField = uieditfield(app.PIDTab, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KValueChanged, true);
            app.KEditField.Limits = [0 20];
            app.KEditField.ValueDisplayFormat = '%11.3g';
            app.KEditField.Value = 2;
            app.TILabel_2 = uilabel(app.PIDTab);
            app.TILabel_2.Text = 'TI';
            app.TiEditField = uieditfield(app.PIDTab, 'numeric');
            app.TiEditField.ValueChangedFcn = createCallbackFcn(app, @TiValueChanged, true);
            app.TiEditField.Limits = [0 Inf];
            app.TiEditField.ValueDisplayFormat = '%11.3g';
            app.TiEditField.Value = Inf;
            app.TDLabel_2 = uilabel(app.PIDTab);
            app.TDLabel_2.Text = 'TD';
            app.TdEditField = uieditfield(app.PIDTab, 'numeric');
            app.TdEditField.ValueChangedFcn = createCallbackFcn(app, @TdValueChanged, true);
            app.TdEditField.Limits = [0 10];
            app.TdEditField.ValueDisplayFormat = '%11.3g';
            app.TankSwitch = uiswitch(app.PIDTab, 'slider');
            app.TankSwitch.Items = {'Upper tank', 'Lower tank'};
            app.TankSwitch.ValueChangedFcn = createCallbackFcn(app, @TankSwitchValueChanged, true);
            app.TankSwitch.Value = 'Lower tank';
            app.SimulationspeedupDropDownLabel_2 = uilabel(app.PIDTab);
            app.SimulationspeedupDropDownLabel_2.Text = 'Simulation speed up';
            app.PIDSimulationspeedupDropDown = uidropdown(app.PIDTab);
            app.PIDSimulationspeedupDropDown.Items = {'1x', '2x', '3x', '4x', '5x'};
            app.PIDSimulationspeedupDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationspeedupDropDownValueChanged, true);
            app.PIDSimulationspeedupDropDown.Value = '1x';
            app.LQRTab_2 = uitab(app.ManualPIDMPC);
            app.LQRTab_2.Title = 'LQR';
            app.LQRPenaltyPanel = uipanel(app.LQRTab_2);
            app.LQRPenaltyPanel.Title = 'Penalty';
            app.InputpenaltyEditFieldLabel_2 = uilabel(app.LQRPenaltyPanel);
            app.InputpenaltyEditFieldLabel_2.Text = 'Input penalty';
            app.LQRInputpenaltyEditField = uieditfield(app.LQRPenaltyPanel, 'numeric');
            app.LQRInputpenaltyEditField.LowerLimitInclusive = 'off';
            app.LQRInputpenaltyEditField.ValueChangedFcn = createCallbackFcn(app, @LQRInputpenaltyEditFieldValueChanged, true);
            app.LQRInputpenaltyEditField.Limits = [0 10];
            app.LQRInputpenaltyEditField.Value = 1;
            app.LowertankpenaltyLabel_2 = uilabel(app.LQRPenaltyPanel);
            app.LowertankpenaltyLabel_2.Text = 'Lower tank penalty';
            app.LQRTank2penaltyEditField = uieditfield(app.LQRPenaltyPanel, 'numeric');
            app.LQRTank2penaltyEditField.ValueChangedFcn = createCallbackFcn(app, @LQRTank2penaltyEditFieldValueChanged, true);
            app.LQRTank2penaltyEditField.Limits = [0 10];
            app.LQRTank2penaltyEditField.Value = 1;
            app.UppertankpenaltyLabel_2 = uilabel(app.LQRPenaltyPanel);
            app.UppertankpenaltyLabel_2.Text = 'Upper tank  penalty';
            app.LQRTank1penaltyEditField = uieditfield(app.LQRPenaltyPanel, 'numeric');
            app.LQRTank1penaltyEditField.ValueChangedFcn = createCallbackFcn(app, @LQRTank1penaltyEditFieldValueChanged, true);
            app.LQRTank1penaltyEditField.Limits = [0 10];
            app.ReferenceLabel_3 = uilabel(app.LQRPenaltyPanel);
            app.ReferenceLabel_3.Text = 'Reference';
            app.LQRRefEditField = uieditfield(app.LQRPenaltyPanel, 'numeric');
            app.LQRRefEditField.ValueChangedFcn = createCallbackFcn(app, @LQRRefEditFieldValueChanged, true);
            app.LQRRefEditField.Limits = [0 100];
            app.LQRRefEditField.RoundFractionalValues = 'on';
            app.LQRRefEditField.ValueDisplayFormat = '%11.3g';
            app.LQRRefEditField.Value = 60;
            app.DisturbancecompensationPanel_2 = uipanel(app.LQRTab_2);
            app.DisturbancecompensationPanel_2.Title = 'Disturbance compensation';
            app.LQRDisturbancecompensationSwitch = uiswitch(app.DisturbancecompensationPanel_2, 'slider');
            app.LQRDisturbancecompensationSwitch.ValueChangedFcn = createCallbackFcn(app, @LQRDisturbancecompensationSwitchValueChanged, true);
            app.ReferencechoicePanelLQR = uipanel(app.LQRTab_2);
            app.ReferencechoicePanelLQR.Title = 'Reference choice';
            app.ReferencechoiceSwitchLQR = uiswitch(app.ReferencechoicePanelLQR, 'slider');
            app.ReferencechoiceSwitchLQR.Items = {'Constant', 'Sinusoid'};
            app.ReferencechoiceSwitchLQR.ValueChangedFcn = createCallbackFcn(app, @ReferencechoiceSwitchLQRValueChanged, true);
            app.ReferencechoiceSwitchLQR.Value = 'Constant';
            app.SimulationspeedupDropDownLabel_3 = uilabel(app.LQRTab_2);
            app.SimulationspeedupDropDownLabel_3.Text = 'Simulation speed up';
            app.LQRSimulationspeedupDropDown = uidropdown(app.LQRTab_2);
            app.LQRSimulationspeedupDropDown.Items = {'1x', '2x', '3x', '4x', '5x'};
            app.LQRSimulationspeedupDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationspeedupDropDownValueChanged, true);
            app.LQRSimulationspeedupDropDown.Value = '1x';
            app.MPCTab = uitab(app.ManualPIDMPC);
            app.MPCTab.Title = 'MPC';
            app.PenaltyPanel = uipanel(app.MPCTab);
            app.PenaltyPanel.Title = 'Penalty';
            app.InputpenaltyEditFieldLabel = uilabel(app.PenaltyPanel);
            app.InputpenaltyEditFieldLabel.Text = 'Input penalty';
            app.MPCInputpenaltyEditField = uieditfield(app.PenaltyPanel, 'numeric');
            app.MPCInputpenaltyEditField.ValueChangedFcn = createCallbackFcn(app, @MPCInputpenaltyEditFieldValueChanged, true);
            app.MPCInputpenaltyEditField.Limits = [0 10];
            app.MPCInputpenaltyEditField.Value = 1;
            app.LowertankpenaltyLabel = uilabel(app.PenaltyPanel);
            app.LowertankpenaltyLabel.Text = 'Lower tank penalty';
            app.MPCTank2penaltyEditField = uieditfield(app.PenaltyPanel, 'numeric');
            app.MPCTank2penaltyEditField.ValueChangedFcn = createCallbackFcn(app, @MPCTank2penaltyEditFieldValueChanged, true);
            app.MPCTank2penaltyEditField.Limits = [0 10];
            app.MPCTank2penaltyEditField.Value = 1;
            app.UppertankpenaltyLabel = uilabel(app.PenaltyPanel);
            app.UppertankpenaltyLabel.Text = 'Upper tank penalty';
            app.MPCTank1penaltyEditField = uieditfield(app.PenaltyPanel, 'numeric');
            app.MPCTank1penaltyEditField.ValueChangedFcn = createCallbackFcn(app, @MPCTank1penaltyEditFieldValueChanged, true);
            app.MPCTank1penaltyEditField.Limits = [0 10];
            app.ReferenceLabel_4 = uilabel(app.PenaltyPanel);
            app.ReferenceLabel_4.Text = 'Reference';
            app.MPCRefEditField = uieditfield(app.PenaltyPanel, 'numeric');
            app.MPCRefEditField.ValueChangedFcn = createCallbackFcn(app, @MPCRefEditFieldValueChanged, true);
            app.MPCRefEditField.Limits = [0 100];
            app.MPCRefEditField.ValueDisplayFormat = '%11.3g';
            app.MPCRefEditField.Value = 60;
            app.PredictionhorizonPanel = uipanel(app.MPCTab);
            app.PredictionhorizonPanel.Title = 'Prediction horizon';
            app.ClosedloopEditFieldLabel = uilabel(app.PredictionhorizonPanel);
            app.ClosedloopEditFieldLabel.Text = 'Closed loop';
            app.CloseloopEditField = uieditfield(app.PredictionhorizonPanel, 'numeric');
            app.CloseloopEditField.ValueChangedFcn = createCallbackFcn(app, @CloseloopEditFieldValueChanged, true);
            app.CloseloopEditField.Limits = [1 15];
            app.CloseloopEditField.RoundFractionalValues = 'on';
            app.CloseloopEditField.Value = 10;
            app.OpenloopEditFieldLabel = uilabel(app.PredictionhorizonPanel);
            app.OpenloopEditFieldLabel.Text = 'Open loop';
            app.OpenloopEditField = uieditfield(app.PredictionhorizonPanel, 'numeric');
            app.OpenloopEditField.ValueChangedFcn = createCallbackFcn(app, @OpenloopEditFieldValueChanged, true);
            app.OpenloopEditField.Limits = [50 100];
            app.OpenloopEditField.RoundFractionalValues = 'on';
            app.OpenloopEditField.Value = 100;
            app.MPCchoicePanel = uipanel(app.MPCTab);
            app.MPCchoicePanel.Title = 'MPC choice';
            app.CloseOpenMPCSwitch = uiswitch(app.MPCchoicePanel, 'slider');
            app.CloseOpenMPCSwitch.Items = {'Closed loop', 'Open loop'};
            app.CloseOpenMPCSwitch.ValueChangedFcn = createCallbackFcn(app, @CloseOpenMPCSwitchValueChanged, true);
            app.CloseOpenMPCSwitch.Value = 'Closed loop';
            app.ReferencechoicePanel = uipanel(app.MPCTab);
            app.ReferencechoicePanel.Title = 'Reference choice';
            app.ReferencechoiceSwitch = uiswitch(app.ReferencechoicePanel, 'slider');
            app.ReferencechoiceSwitch.Items = {'Constant', 'Sinusoid'};
            app.ReferencechoiceSwitch.ValueChangedFcn = createCallbackFcn(app, @ReferencechoiceSwitchValueChanged, true);
            app.ReferencechoiceSwitch.Value = 'Constant';
            app.RicattiterminalpenaltyPanel = uipanel(app.MPCTab);
            app.RicattiterminalpenaltyPanel.Title = 'Ricatti terminal penalty';
            app.RiccatiterminalpenaltySwitch = uiswitch(app.RicattiterminalpenaltyPanel, 'slider');
            app.RiccatiterminalpenaltySwitch.ValueChangedFcn = createCallbackFcn(app, @RiccatiterminalpenaltyValueChanged, true);
            app.DisturbancecompensationPanel = uipanel(app.MPCTab);
            app.DisturbancecompensationPanel.Title = 'Disturbance compensation';
            app.DisturbancecompensationSwitch = uiswitch(app.DisturbancecompensationPanel, 'slider');
            app.DisturbancecompensationSwitch.ValueChangedFcn = createCallbackFcn(app, @DisturbancecompensationSwitchValueChanged, true);
            app.ReferencepreviewPanel = uipanel(app.MPCTab);
            app.ReferencepreviewPanel.Title = 'Reference preview';
            app.ReferencepreviewSwitch = uiswitch(app.ReferencepreviewPanel, 'slider');
            app.ReferencepreviewSwitch.ValueChangedFcn = createCallbackFcn(app, @ReferencepreviewValueChanged, true);
            app.ConstraintsPanel = uipanel(app.MPCTab);
            app.ConstraintsPanel.Title = 'Constraints';
            app.InputconstraintCheckBox = uicheckbox(app.ConstraintsPanel);
            app.InputconstraintCheckBox.ValueChangedFcn = createCallbackFcn(app, @InputconstraintCheckBoxValueChanged, true);
            app.InputconstraintCheckBox.Text = 'Input constraint';
            app.InputmaxLabel = uilabel(app.ConstraintsPanel);
            app.InputmaxLabel.Enable = 'off';
            app.InputmaxLabel.Text = 'Input max';
            app.MPCInputConstraintMax = uieditfield(app.ConstraintsPanel, 'numeric');
            app.MPCInputConstraintMax.ValueChangedFcn = createCallbackFcn(app, @MPCInputConstraintMaxValueChanged, true);
            app.MPCInputConstraintMax.Limits = [50 100];
            app.MPCInputConstraintMax.RoundFractionalValues = 'on';
            app.MPCInputConstraintMax.Enable = 'off';
            app.MPCInputConstraintMax.Value = 100;
            app.InputminLabel = uilabel(app.ConstraintsPanel);
            app.InputminLabel.Enable = 'off';
            app.InputminLabel.Text = 'Input min';
            app.MPCInputConstraintMin = uieditfield(app.ConstraintsPanel, 'numeric');
            app.MPCInputConstraintMin.ValueChangedFcn = createCallbackFcn(app, @MPCInputConstraintMinValueChanged, true);
            app.MPCInputConstraintMin.Limits = [0 50];
            app.MPCInputConstraintMin.RoundFractionalValues = 'on';
            app.MPCInputConstraintMin.Enable = 'off';
            app.StateconstraintCheckBox = uicheckbox(app.ConstraintsPanel);
            app.StateconstraintCheckBox.ValueChangedFcn = createCallbackFcn(app, @StateconstraintCheckBoxValueChanged, true);
            app.StateconstraintCheckBox.Text = 'State constraint';
            app.TankmaxLabel = uilabel(app.ConstraintsPanel);
            app.TankmaxLabel.Enable = 'off';
            app.TankmaxLabel.Text = 'Tank max';
            app.MPCTankConstraintMax = uieditfield(app.ConstraintsPanel, 'numeric');
            app.MPCTankConstraintMax.ValueChangedFcn = createCallbackFcn(app, @MPCTankConstraintMaxValueChanged, true);
            app.MPCTankConstraintMax.Limits = [50 100];
            app.MPCTankConstraintMax.RoundFractionalValues = 'on';
            app.MPCTankConstraintMax.Enable = 'off';
            app.MPCTankConstraintMax.Value = 100;
            app.TankminLabel = uilabel(app.ConstraintsPanel);
            app.TankminLabel.Enable = 'off';
            app.TankminLabel.Text = 'Tank min';
            app.MPCTankConstraintMin = uieditfield(app.ConstraintsPanel, 'numeric');
            app.MPCTankConstraintMin.ValueChangedFcn = createCallbackFcn(app, @MPCTankConstraintMinValueChanged, true);
            app.MPCTankConstraintMin.Limits = [0 50];
            app.MPCTankConstraintMin.RoundFractionalValues = 'on';
            app.MPCTankConstraintMin.Enable = 'off';
            app.ModelparametersButton = uibutton(app.MPCTab, 'push');
            app.ModelparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ModelparametersButtonPushed, true);
            app.ModelparametersButton.Text = 'Model parameters';
            app.ModelsaveButton = uibutton(app.MPCTab, 'push');
            app.ModelsaveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelsaveButtonPushed, true);
            app.ModelsaveButton.Text = 'Model save';
            app.CapturePanel = uipanel(app.DoubleTankProcess);
            app.CapturePanel.Title = 'Capture';
            app.CaptureButton = uibutton(app.CapturePanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.sEditFieldLabel = uilabel(app.CapturePanel);
            app.sEditFieldLabel.Text = 's';
            app.SecondsToCaptureEditField = uieditfield(app.CapturePanel, 'numeric');
            app.SecondsToCaptureEditField.ValueChangedFcn = createCallbackFcn(app, @SecondsToCaptureEditFieldValueChanged, true);
            app.SecondsToCaptureEditField.Limits = [10 600];
            app.SecondsToCaptureEditField.Value = 100;
            app.SavetoButton = uibutton(app.CapturePanel, 'push');
            app.SavetoButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoButtonPushed, true);
            app.SavetoButton.Text = 'Save to';
            app.matEditFieldLabel = uilabel(app.CapturePanel);
            app.matEditFieldLabel.Text = '.mat';
            app.SavetoFileEditField = uieditfield(app.CapturePanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = two_tank_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoubleTankProcess)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoubleTankProcess)
        end
    end
end